classdef CustomStim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StimulationtypeDropDownLabel   matlab.ui.control.Label
        StimulationtypeDropDown        matlab.ui.control.DropDown
        CenteregLaseronsetVEditFieldLabel  matlab.ui.control.Label
        LaseronsetVEditField           matlab.ui.control.NumericEditField
        MaxValueVEditFieldLabel        matlab.ui.control.Label
        MaxValueVEditField             matlab.ui.control.NumericEditField
        DurationsEditFieldLabel        matlab.ui.control.Label
        DurationsEditField             matlab.ui.control.NumericEditField
        OkButton                       matlab.ui.control.Button
        PreviewButton                  matlab.ui.control.Button
        StimDescriptionTextAreaLabel   matlab.ui.control.Label
        StimDescriptionTextArea        matlab.ui.control.TextArea
        NbofrepetitionsEditFieldLabel  matlab.ui.control.Label
        NbofrepetitionsEditField       matlab.ui.control.NumericEditField
        IntertrialdurationEditFieldLabel  matlab.ui.control.Label
        IntertrialdurationEditField    matlab.ui.control.EditField
        FstimstartEditFieldLabel       matlab.ui.control.Label
        FstimstartEditField            matlab.ui.control.NumericEditField
        FstimstopEditFieldLabel        matlab.ui.control.Label
        FstimstopEditField             matlab.ui.control.NumericEditField
        NbofcyclesEditFieldLabel       matlab.ui.control.Label
        NbofcyclesEditField            matlab.ui.control.NumericEditField
        BaselinesEditFieldLabel        matlab.ui.control.Label
        BaselinesEditField             matlab.ui.control.NumericEditField
        PostTrialsEditFieldLabel       matlab.ui.control.Label
        PostTrialsEditField            matlab.ui.control.NumericEditField
        AnalogPlotAxe                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        CallingApp % Description
        CustomDigitalOutput
        CustomAnalogOutput
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Value changed function: StimulationtypeDropDown
        function AdjustPanelToStimOption(app, event)
            value = app.StimulationtypeDropDown.Value;
            
        end

        % Button pushed function: OkButton
        function ReturnCustomStimOption(app, event)


            UpdateCustomStimOptions(app.CallingApp,app.CustomDigitalOutput,app.CustomAnalogOutput)
            delete(app)
        end

        % Button pushed function: PreviewButton
        function PreviewPlot(app, event)
            
            Fs = app.CallingApp.DaqObject.Rate;
            maxTarget = app.MaxValueVEditField.Value;
            OnsetLaser = app.LaseronsetVEditField.Value;
            Range = (maxTarget-OnsetLaser);
            
            
            SignalDur = app.DurationsEditField.Value;
            
            BaseDur = app.BaselinesEditField.Value;
            PostDur = app.PostTrialsEditField.Value;
            
            NbRepets = app.NbofrepetitionsEditField.Value;
            InterTrialDur = eval(app.IntertrialdurationEditField.Value);
   
            if numel(InterTrialDur) == 2
                % Random inter trial duration
                rdelaysTrain = InterTrialDur(1) + (InterTrialDur(2)-InterTrialDur(1)).*rand(NbRepets-1,1); 
            elseif  numel(InterTrialDur) > 2
                warndlg('Inter trial duration must be of the form [minDuration maxDuration] or a single nb fixed duration')
            elseif numel(InterTrialDur) == 1
                % Fixed inter trial delay
                rdelaysTrain = InterTrialDur*ones(NbRepets-1,1);
            else
                warndlg('unknown error')
            end
            
            switch app.StimulationtypeDropDown.Value
                case 'Fixed Period Sinusoid'
                    
                    
                    Fstim = app.FstimstartEditField.Value;
                    NbCycles = app.NbofcyclesEditField.Value;
                    t= 0:1/Fs:(1/Fstim*(NbCycles))-1/Fs;
                    StimIter = sin(2*pi*Fstim*t);
                    StimIter = StimIter';
                    
                    
                    ScaledStimIter = (StimIter+1)/2; % Sinusoid between 0 and 1
                    ScaledStimIter = (ScaledStimIter*Range) + (OnsetLaser);
%                     tvec=linspace(1/Fs,length(app.CustomAnalogOutput)/Fs,length(app.CustomAnalogOutput));
%                     plot(app.AnalogPlotAxe, t,x);
                    
                    SinSeq = [];
                    digOut = [];
                    for t=1:NbRepets
                        if NbRepets==1                             
                            SinSeq = [SinSeq; zeros(BaseDur*Fs,1); ScaledStimIter; zeros(PostDur*Fs,1)];
                            digOut = [digOut;zeros(BaseDur*Fs,1); ones(length(ScaledStimIter),1);  zeros(PostDur*Fs,1)];
                        elseif t==1 && BaseDur > 0
                            SinSeq = [zeros(BaseDur*Fs,1); ScaledStimIter];
                            digOut = [zeros(BaseDur*Fs,1); ones(length(ScaledStimIter),1)];
                        elseif t==NbRepets && PostDur > 0
                            SinSeq = [SinSeq; zeros(round(rdelaysTrain(t-1)*Fs),1); ScaledStimIter; zeros(PostDur*Fs,1)];
                            digOut = [digOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ones(length(ScaledStimIter),1);  zeros(PostDur*Fs,1)];
                        else
                            SinSeq  = [SinSeq; zeros(round(rdelaysTrain(t-1)*Fs),1); ScaledStimIter ];
                            digOut = [digOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ones(length(ScaledStimIter),1)  ];
                        end
                    end
                    app.CustomAnalogOutput = SinSeq;
                    app.CustomDigitalOutput = digOut;                    
                    
                case 'Chirp'

                    t = -SignalDur/2:1/Fs:SignalDur/2;
                    fo = app.FstimstartEditField.Value;
                    f1 = app.FstimstopEditField.Value;
                    t1 = SignalDur/2;
                    y = chirp(t,fo,t1,f1,'quadratic',[],'concave');
                    y=y';
                    ScaledStimIter = (y+1)/2; % Sinusoid between 0 and 1
                    ScaledStimIter = (ScaledStimIter*Range) + (OnsetLaser);
                    
                    chirpOut = [];
                    digOut = [];
                    for t=1:NbRepets
                        if NbRepets == 1
                            chirpOut = [zeros(BaseDur*Fs,1); ScaledStimIter;  zeros(PostDur*Fs,1)];
                            digOut = [zeros(BaseDur*Fs,1); ones(length(ScaledStimIter),1);  zeros(PostDur*Fs,1)];      
                        elseif t==1 && BaseDur > 0
                            chirpOut = [zeros(BaseDur*Fs,1); ScaledStimIter];
                            digOut = [zeros(BaseDur*Fs,1); ones(length(ScaledStimIter),1)];
                        elseif t==NbRepets && PostDur > 0
                            chirpOut = [chirpOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ScaledStimIter; zeros(PostDur*Fs,1)];
                            digOut = [digOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ones(length(ScaledStimIter),1);  zeros(PostDur*Fs,1)];
                        else
                            chirpOut = [chirpOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ScaledStimIter ];
                            digOut = [digOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ones(length(ScaledStimIter),1)  ];
                        end
                    end
                    app.CustomAnalogOutput = chirpOut;
                    app.CustomDigitalOutput = digOut;
                    
                case 'Gaussian White Noise'
                    noise = wgn(Fs*SignalDur,1,0);
                    
                    if BaseDur > 0 && PostDur > 0
                        app.CustomAnalogOutput = [zeros(Fs*BaseDur,1); noise*(maxTarget-OnsetLaser)/2; zeros(Fs*PostDur,1)];
                        app.CustomDigitalOutput = [zeros(Fs*BaseDur,1); ones(length(noise),1); zeros(Fs*PostDur,1)];
                    elseif BaseDur > 0 && PostDur == 0
                        app.CustomAnalogOutput = [zeros(Fs*BaseDur,1); noise*(maxTarget-OnsetLaser)/2];
                        app.CustomDigitalOutput = [zeros(Fs*BaseDur,1); ones(length(app.CustomAnalogOutput),1)];                        
                        
                    elseif  BaseDur == 0 && PostDur > 0
                        app.CustomAnalogOutput = [ noise*(maxTarget-OnsetLaser)/2; zeros(Fs*PostDur,1)];
                        app.CustomDigitalOutput = [ ones(length(app.CustomAnalogOutput),1); zeros(Fs*PostDur,1)];                        
                    elseif  BaseDur == 0 && PostDur == 0
                        app.CustomAnalogOutput = [noise*(maxTarget-OnsetLaser)/2];
                        app.CustomDigitalOutput = [ones(length(app.CustomAnalogOutput),1)];                        
                    else 
                        wardlg('error in Pre or Post stim duration')
                    end
                    
                otherwise
                    warndlg('Wrong stim type')
            end
            
            
            tvec=linspace(1/Fs,length(app.CustomAnalogOutput)/Fs,length(app.CustomAnalogOutput));
            plot(app.AnalogPlotAxe, tvec,app.CustomAnalogOutput);
%             plot(app.AnalogAxe, tvec,app.AnaLine);
%             linkaxes([app.DigitalAxe app.AnalogAxe], 'x')
            xlim(app.AnalogPlotAxe,[tvec(1) tvec(end)])
            app.OkButton.Enable = 1;      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StimulationtypeDropDownLabel = uilabel(app.UIFigure);
            app.StimulationtypeDropDownLabel.Text = 'Stimulation type: ';
            app.StimulationtypeDropDown = uidropdown(app.UIFigure);
            app.StimulationtypeDropDown.Items = {'Fixed Period Sinusoid', 'Chirp', 'Gaussian White Noise'};
            app.StimulationtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AdjustPanelToStimOption, true);
            app.StimulationtypeDropDown.Value = 'Fixed Period Sinusoid';
            app.CenteregLaseronsetVEditFieldLabel = uilabel(app.UIFigure);
            app.CenteregLaseronsetVEditFieldLabel.Text = 'Center (e.g. Laser onset, V)';
            app.LaseronsetVEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaseronsetVEditField.Value = 0.5;
            app.MaxValueVEditFieldLabel = uilabel(app.UIFigure);
            app.MaxValueVEditFieldLabel.Text = 'Max Value (V)';
            app.MaxValueVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxValueVEditField.Value = 0.95;
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.Value = 30;
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnCustomStimOption, true);
            app.OkButton.Enable = 'off';
            app.OkButton.Text = 'Ok';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewPlot, true);
            app.PreviewButton.Text = 'Preview';
            app.StimDescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.StimDescriptionTextAreaLabel.Text = 'Stim Description';
            app.StimDescriptionTextArea = uitextarea(app.UIFigure);
            app.StimDescriptionTextArea.Editable = 'off';
            app.NbofrepetitionsEditFieldLabel = uilabel(app.UIFigure);
            app.NbofrepetitionsEditFieldLabel.Text = 'Nb of repetitions';
            app.NbofrepetitionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NbofrepetitionsEditField.Value = 5;
            app.IntertrialdurationEditFieldLabel = uilabel(app.UIFigure);
            app.IntertrialdurationEditFieldLabel.Text = 'Inter-trial duration';
            app.IntertrialdurationEditField = uieditfield(app.UIFigure, 'text');
            app.IntertrialdurationEditField.Value = '[3 5]';
            app.FstimstartEditFieldLabel = uilabel(app.UIFigure);
            app.FstimstartEditFieldLabel.Text = 'Fstim start';
            app.FstimstartEditField = uieditfield(app.UIFigure, 'numeric');
            app.FstimstartEditField.Limits = [0 Inf];
            app.FstimstartEditField.Value = 0.5;
            app.FstimstopEditFieldLabel = uilabel(app.UIFigure);
            app.FstimstopEditFieldLabel.Text = 'Fstim stop';
            app.FstimstopEditField = uieditfield(app.UIFigure, 'numeric');
            app.FstimstopEditField.Limits = [0 Inf];
            app.FstimstopEditField.Value = 100;
            app.NbofcyclesEditFieldLabel = uilabel(app.UIFigure);
            app.NbofcyclesEditFieldLabel.Text = 'Nb of cycles';
            app.NbofcyclesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NbofcyclesEditField.Limits = [0 Inf];
            app.NbofcyclesEditField.Value = 20;
            app.BaselinesEditFieldLabel = uilabel(app.UIFigure);
            app.BaselinesEditFieldLabel.Text = 'Baseline (s)';
            app.BaselinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselinesEditField.Limits = [0 Inf];
            app.BaselinesEditField.Value = 5;
            app.PostTrialsEditFieldLabel = uilabel(app.UIFigure);
            app.PostTrialsEditFieldLabel.Text = 'Post-Trial (s)';
            app.PostTrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PostTrialsEditField.Limits = [0 Inf];
            app.PostTrialsEditField.Value = 5;
            app.AnalogPlotAxe = uiaxes(app.UIFigure);
            title(app.AnalogPlotAxe, 'Custom stimulation pattern preview')
            xlabel(app.AnalogPlotAxe, 'Time (s)')
            ylabel(app.AnalogPlotAxe, 'Light Input (V)')
            zlabel(app.AnalogPlotAxe, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomStim(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end