classdef CustomStimBis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StimulationtypeDropDownLabel   matlab.ui.control.Label
        StimulationtypeDropDown        matlab.ui.control.DropDown
        MinValueegLaseronsetVLabel     matlab.ui.control.Label
        LaseronsetVEditField           matlab.ui.control.NumericEditField
        MaxValueVEditFieldLabel        matlab.ui.control.Label
        MaxValueVEditField             matlab.ui.control.NumericEditField
        DurationsEditFieldLabel        matlab.ui.control.Label
        DurationsEditField             matlab.ui.control.NumericEditField
        OkButton                       matlab.ui.control.Button
        PreviewButton                  matlab.ui.control.Button
        StimDescriptionTextAreaLabel   matlab.ui.control.Label
        StimDescriptionTextArea        matlab.ui.control.TextArea
        NbofrepetitionsEditFieldLabel  matlab.ui.control.Label
        NbofrepetitionsEditField       matlab.ui.control.NumericEditField
        IntertrialdurationEditFieldLabel  matlab.ui.control.Label
        IntertrialdurationEditField    matlab.ui.control.EditField
        FstimstartLabel                matlab.ui.control.Label
        FstimstartEditField            matlab.ui.control.NumericEditField
        FstimstopEditFieldLabel        matlab.ui.control.Label
        FstimstopEditField             matlab.ui.control.NumericEditField
        NbofcyclesEditFieldLabel       matlab.ui.control.Label
        NbofcyclesEditField            matlab.ui.control.NumericEditField
        BaselinesEditFieldLabel        matlab.ui.control.Label
        BaselinesEditField             matlab.ui.control.NumericEditField
        PostTrialsEditFieldLabel       matlab.ui.control.Label
        PostTrialsEditField            matlab.ui.control.NumericEditField
        FreqTrainLabel                 matlab.ui.control.Label
        FreqTrainTextLabel             matlab.ui.control.Label
        FreqTrainLabel_2               matlab.ui.control.Label
        FreqTrainTextLabel_2           matlab.ui.control.Label
        PulseDurationsEditFieldLabel   matlab.ui.control.Label
        PulseDurationsEditField        matlab.ui.control.NumericEditField
        InterPulseIntervalsEditFieldLabel  matlab.ui.control.Label
        InterPulseIntervalsEditField   matlab.ui.control.NumericEditField
        PulseNbEditFieldLabel          matlab.ui.control.Label
        PulseNbEditField               matlab.ui.control.NumericEditField
        LightProtocolButtonGroup       matlab.ui.container.ButtonGroup
        DigitalregularpulsesButton     matlab.ui.control.RadioButton
        AnalogsmoothedpulsesButton     matlab.ui.control.RadioButton
        SmoothingwindowEditFieldLabel  matlab.ui.control.Label
        SmoothingwindowEditField       matlab.ui.control.NumericEditField
        AnalogPlotAxe                  matlab.ui.control.UIAxes
        DigitalPlotAxe                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        CallingApp % Description
        CustomDigitalOutput
        CustomAnalogOutput
    end
    
    methods (Access = private)
        
        function results = CheckStim(app)
                        app.CallingApp.DaqObject.Rate = str2double(app.SamplingrateHzEditField.Value) ; 


        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
        end

        % Value changed function: StimulationtypeDropDown
        function AdjustPanelToStimOption(app, event)
            value = app.StimulationtypeDropDown.Value;
            
        end

        % Button pushed function: OkButton
        function ReturnCustomStimOption(app, event)
            params = app;
            UpdateCustomStimOptions(app.CallingApp,app.CustomDigitalOutput,app.CustomAnalogOutput,params)
            delete(app)
        end

        % Button pushed function: PreviewButton
        function PreviewPlot(app, event)
            
            Fs = app.CallingApp.DaqObject.Rate;
            maxTarget = app.MaxValueVEditField.Value;
            OnsetLaser = app.LaseronsetVEditField.Value;
            Range = (maxTarget-OnsetLaser);
                        
            SignalDur = app.DurationsEditField.Value;
            
            BaseDur = app.BaselinesEditField.Value;
            PostDur = app.PostTrialsEditField.Value;
            
            NbRepets = app.NbofrepetitionsEditField.Value;
            InterTrialDur = eval(app.IntertrialdurationEditField.Value);
   
            if numel(InterTrialDur) == 2
                % Random inter trial duration
                rdelaysTrain = InterTrialDur(1) + (InterTrialDur(2)-InterTrialDur(1)).*rand(NbRepets-1,1); 
            elseif  numel(InterTrialDur) > 2
                warndlg('Inter trial duration must be of the form [minDuration maxDuration] or a single nb fixed duration')
            elseif numel(InterTrialDur) == 1
                % Fixed inter trial delay
                rdelaysTrain = InterTrialDur*ones(NbRepets-1,1);
            else
                warndlg('unknown error')
            end
            
            switch app.StimulationtypeDropDown.Value
                
                case 'Pulses train'


                    %% Monotonous Single pulses in trains, random or fixed Inter train interval
                    % Signal Preparation for full stim protocol
                    AnaValue = app.MaxValueVEditField.Value;
                    PulseDur = app.PulseDurationsEditField.Value;
                    PulseNb =  app.PulseNbEditField.Value;            
                    PulseInterv = app.InterPulseIntervalsEditField.Value;
                    
                    TrainNb = app.NbofrepetitionsEditField.Value;
                    TrainInterv = eval(app.IntertrialdurationEditField.Value);
                    
        
                    % Train and pulses generation  
                    app.CustomAnalogOutput = [];
                    app.CustomDigitalOutput = [];
              
        
                    if numel(TrainInterv) == 1
        
                        % First add zeros before pulses according to TrainInterv
                        app.CustomDigitalOutput=[app.CustomDigitalOutput; zeros(round(TrainInterv*app.CallingApp.DaqObject.Rate),1)];
                        
                        for t=1:TrainNb
                            if numel(PulseInterv) == 1
                                for p=1:PulseNb
                                    app.CustomDigitalOutput=[app.CustomDigitalOutput; ones(round(PulseDur*app.CallingApp.DaqObject.Rate),1); zeros(round(PulseInterv(1)*app.CallingApp.DaqObject.Rate),1)];
                                end
                                app.CustomDigitalOutput=[app.CustomDigitalOutput; zeros(round(TrainInterv*app.CallingApp.DaqObject.Rate),1)];
                            
                            elseif numel(PulseInterv) == 2
                                for p=1:PulseNb
                                    app.CustomDigitalOutput=[app.CustomDigitalOutput; ones(round(PulseDur*app.CallingApp.DaqObject.Rate),1); zeros(round(rdelays(p+1)*app.CallingApp.DaqObject.Rate),1)];
                                end
                            end
                                
                        end
                        app.CustomDigitalOutput=[app.CustomDigitalOutput; zeros(round(TrainInterv*app.CallingApp.DaqObject.Rate),1)];
                    
                    elseif numel(TrainInterv) == 2
                        
                        rdelaysTrain = TrainInterv(1) + (TrainInterv(2)-TrainInterv(1)).*rand(TrainNb+1,1); 
                        app.CustomDigitalOutput=[app.CustomDigitalOutput; zeros(round(rdelaysTrain(1)*app.CallingApp.DaqObject.Rate),1)];
        
                        for t=1:TrainNb
                            if numel(PulseInterv) == 1
                                for p=1:PulseNb
                                    app.CustomDigitalOutput=[app.CustomDigitalOutput; ones(round(PulseDur*app.CallingApp.DaqObject.Rate),1); zeros(round(PulseInterv(1)*app.CallingApp.DaqObject.Rate),1)];
                                end
                                app.CustomDigitalOutput=[app.CustomDigitalOutput; zeros(round(rdelaysTrain(t+1)*app.CallingApp.DaqObject.Rate),1)]; % Fix interval after each train
                            
                            elseif numel(PulseInterv) == 2
                                
                                rdelaysPulse = PulseInterv(1) + (PulseInterv(2)-PulseInterv(1)).*rand(PulseNb,1); % Random interval after each pulse
                                for p=1:PulseNb
                                    app.CustomDigitalOutput=[app.CustomDigitalOutput; ones(round(PulseDur*app.CallingApp.DaqObject.Rate),1); zeros(round(rdelaysPulse(p)*app.CallingApp.DaqObject.Rate),1)];
                                end
                                app.CustomDigitalOutput=[app.CustomDigitalOutput; zeros(round(rdelaysTrain(t+1)*app.CallingApp.DaqObject.Rate),1)]; % Fix interval after each train
        
                            end                    
                        end          
                    end
                    % End of train and pulses generation
                    
                    app.CustomAnalogOutput = ones(length(app.CustomDigitalOutput),1)*app.PowerVEditField.Value;
        
                    % Converting in analog trace to smooth pulse edges if
                    % requested
                    if app.AnalogsmoothedpulsesButton.Value == 1
                       app.CustomAnalogOutput = app.CustomDigitalOutput .* app.PowerVEditField.Value;
                       app.CustomAnalogOutput = smooth(app.CustomAnalogOutput,app.SmoothingwindowEditField.Value);
                       app.CustomDigitalOutput = ones(length(app.CustomAnalogOutput),1);
                    end
            
%                     app.LoadButton.Enable = 1;  
%         end
                case 'Fixed Period Sinusoid'
                    
                    
                    Fstim = app.FstimstartEditField.Value;
                    NbCycles = app.NbofcyclesEditField.Value;
                    t= 0:1/Fs:(1/Fstim*(NbCycles))-1/Fs;
                    StimIter = sin(2*pi*Fstim*t);
                    StimIter = StimIter';
                    
                    
                    ScaledStimIter = (StimIter+1)/2; % Sinusoid between 0 and 1
                    ScaledStimIter = (ScaledStimIter*Range) + (OnsetLaser);
%                     tvec=linspace(1/Fs,length(app.CustomAnalogOutput)/Fs,length(app.CustomAnalogOutput));
%                     plot(app.AnalogPlotAxe, t,x);
                    
                    SinSeq = [];
                    digOut = [];
                    for t=1:NbRepets
                        if NbRepets==1                             
                            SinSeq = [SinSeq; zeros(BaseDur*Fs,1); ScaledStimIter; zeros(PostDur*Fs,1)];
                            digOut = [digOut;zeros(BaseDur*Fs,1); ones(length(ScaledStimIter),1);  zeros(PostDur*Fs,1)];
                        elseif t==1 && BaseDur > 0
                            SinSeq = [zeros(BaseDur*Fs,1); ScaledStimIter];
                            digOut = [zeros(BaseDur*Fs,1); ones(length(ScaledStimIter),1)];
                        elseif t==NbRepets && PostDur > 0
                            SinSeq = [SinSeq; zeros(round(rdelaysTrain(t-1)*Fs),1); ScaledStimIter; zeros(PostDur*Fs,1)];
                            digOut = [digOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ones(length(ScaledStimIter),1);  zeros(PostDur*Fs,1)];
                        else
                            SinSeq  = [SinSeq; zeros(round(rdelaysTrain(t-1)*Fs),1); ScaledStimIter ];
                            digOut = [digOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ones(length(ScaledStimIter),1)  ];
                        end
                    end
                    app.CustomAnalogOutput = SinSeq;
                    app.CustomDigitalOutput = digOut;                    
                    
                case 'Chirp'

                    t = -SignalDur/2:1/Fs:SignalDur/2;
                    fo = app.FstimstartEditField.Value;
                    f1 = app.FstimstopEditField.Value;
                    t1 = SignalDur/2;
                    y = chirp(t,fo,t1,f1,'quadratic',[],'concave');
                    y=y';
                    ScaledStimIter = (y+1)/2; % Sinusoid between 0 and 1
                    ScaledStimIter = (ScaledStimIter*Range) + (OnsetLaser) - (maxTarget-OnsetLaser);
                    
                    chirpOut = [];
                    digOut = [];
                    for t=1:NbRepets
                        if NbRepets == 1
                            chirpOut = [zeros(BaseDur*Fs,1); ScaledStimIter;  zeros(PostDur*Fs,1)];
                            digOut = [zeros(BaseDur*Fs,1); ones(length(ScaledStimIter),1);  zeros(PostDur*Fs,1)];      
                        elseif t==1 && BaseDur > 0
                            chirpOut = [zeros(BaseDur*Fs,1); ScaledStimIter];
                            digOut = [zeros(BaseDur*Fs,1); ones(length(ScaledStimIter),1)];
                        elseif t==NbRepets && PostDur > 0
                            chirpOut = [chirpOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ScaledStimIter; zeros(PostDur*Fs,1)];
                            digOut = [digOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ones(length(ScaledStimIter),1);  zeros(PostDur*Fs,1)];
                        else
                            chirpOut = [chirpOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ScaledStimIter ];
                            digOut = [digOut; zeros(round(rdelaysTrain(t-1)*Fs),1); ones(length(ScaledStimIter),1)  ];
                        end
                    end
                    app.CustomAnalogOutput = chirpOut;
                    app.CustomDigitalOutput = digOut;
                    
                case 'Gaussian White Noise'
                    noise = wgn(Fs*SignalDur,1,0);
                    
                    if BaseDur > 0 && PostDur > 0
                        app.CustomAnalogOutput = [zeros(Fs*BaseDur,1); noise*(maxTarget-OnsetLaser)/2; zeros(Fs*PostDur,1)];
                        app.CustomDigitalOutput = [zeros(Fs*BaseDur,1); ones(length(noise),1); zeros(Fs*PostDur,1)];
                    elseif BaseDur > 0 && PostDur == 0
                        app.CustomAnalogOutput = [zeros(Fs*BaseDur,1); noise*(maxTarget-OnsetLaser)/2];
                        app.CustomDigitalOutput = [zeros(Fs*BaseDur,1); ones(length(app.CustomAnalogOutput),1)];                        
                        
                    elseif  BaseDur == 0 && PostDur > 0
                        app.CustomAnalogOutput = [ noise*(maxTarget-OnsetLaser)/2; zeros(Fs*PostDur,1)];
                        app.CustomDigitalOutput = [ ones(length(app.CustomAnalogOutput),1); zeros(Fs*PostDur,1)];                        
                    elseif  BaseDur == 0 && PostDur == 0
                        app.CustomAnalogOutput = [noise*(maxTarget-OnsetLaser)/2];
                        app.CustomDigitalOutput = [ones(length(app.CustomAnalogOutput),1)];                        
                    else 
                        wardlg('error in Pre or Post stim duration')
                    end
                    
                otherwise
                    warndlg('Wrong stim type')
            end
            
            
            tvec=linspace(1/app.CallingApp.DaqObject.Rate,length(app.CustomDigitalOutput)/app.CallingApp.DaqObject.Rate,length(app.CustomDigitalOutput));
            plot(app.DigitalPlotAxe, tvec,app.CustomDigitalOutput);
            plot(app.AnalogPlotAxe, tvec,app.CustomAnalogOutput);
            linkaxes([app.DigitalPlotAxe app.AnalogPlotAxe], 'x')
            xlim(app.DigitalPlotAxe,[tvec(1) tvec(end)])
            ylim(app.DigitalPlotAxe,[-0.1 1.1])
            
            app.OkButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StimulationtypeDropDownLabel = uilabel(app.UIFigure);
            app.StimulationtypeDropDownLabel.Text = 'Stimulation type: ';
            app.StimulationtypeDropDown = uidropdown(app.UIFigure);
            app.StimulationtypeDropDown.Items = {'Pulses train', 'Fixed Period Sinusoid', 'Chirp', 'Gaussian White Noise'};
            app.StimulationtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @AdjustPanelToStimOption, true);
            app.StimulationtypeDropDown.Value = 'Fixed Period Sinusoid';
            app.MinValueegLaseronsetVLabel = uilabel(app.UIFigure);
            app.MinValueegLaseronsetVLabel.Text = 'Min Value (e.g. Laser onset, V)';
            app.LaseronsetVEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaseronsetVEditField.Value = 0.5;
            app.MaxValueVEditFieldLabel = uilabel(app.UIFigure);
            app.MaxValueVEditFieldLabel.Text = 'Max Value (V)';
            app.MaxValueVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxValueVEditField.Value = 0.95;
            app.DurationsEditFieldLabel = uilabel(app.UIFigure);
            app.DurationsEditFieldLabel.Text = 'Duration (s)';
            app.DurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.DurationsEditField.Value = 30;
            app.OkButton = uibutton(app.UIFigure, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @ReturnCustomStimOption, true);
            app.OkButton.Enable = 'off';
            app.OkButton.Text = 'Ok';
            app.PreviewButton = uibutton(app.UIFigure, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewPlot, true);
            app.PreviewButton.Text = 'Preview';
            app.StimDescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.StimDescriptionTextAreaLabel.Text = 'Stim Description';
            app.StimDescriptionTextArea = uitextarea(app.UIFigure);
            app.StimDescriptionTextArea.Editable = 'off';
            app.NbofrepetitionsEditFieldLabel = uilabel(app.UIFigure);
            app.NbofrepetitionsEditFieldLabel.Text = 'Nb of repetitions';
            app.NbofrepetitionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NbofrepetitionsEditField.Value = 5;
            app.IntertrialdurationEditFieldLabel = uilabel(app.UIFigure);
            app.IntertrialdurationEditFieldLabel.Text = 'Inter-trial duration';
            app.IntertrialdurationEditField = uieditfield(app.UIFigure, 'text');
            app.IntertrialdurationEditField.Value = '[3 5]';
            app.FstimstartLabel = uilabel(app.UIFigure);
            app.FstimstartLabel.Text = 'Fstim (start)';
            app.FstimstartEditField = uieditfield(app.UIFigure, 'numeric');
            app.FstimstartEditField.Limits = [0 Inf];
            app.FstimstartEditField.Value = 0.5;
            app.FstimstopEditFieldLabel = uilabel(app.UIFigure);
            app.FstimstopEditFieldLabel.Text = 'Fstim stop';
            app.FstimstopEditField = uieditfield(app.UIFigure, 'numeric');
            app.FstimstopEditField.Limits = [0 Inf];
            app.FstimstopEditField.Value = 100;
            app.NbofcyclesEditFieldLabel = uilabel(app.UIFigure);
            app.NbofcyclesEditFieldLabel.Text = 'Nb of cycles';
            app.NbofcyclesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NbofcyclesEditField.Limits = [0 Inf];
            app.NbofcyclesEditField.Value = 20;
            app.BaselinesEditFieldLabel = uilabel(app.UIFigure);
            app.BaselinesEditFieldLabel.Text = 'Baseline (s)';
            app.BaselinesEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselinesEditField.Limits = [0 Inf];
            app.BaselinesEditField.Value = 5;
            app.PostTrialsEditFieldLabel = uilabel(app.UIFigure);
            app.PostTrialsEditFieldLabel.Text = 'Post-Trial (s)';
            app.PostTrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PostTrialsEditField.Limits = [0 Inf];
            app.PostTrialsEditField.Value = 5;
            app.FreqTrainLabel = uilabel(app.UIFigure);
            app.FreqTrainTextLabel = uilabel(app.UIFigure);
            app.FreqTrainTextLabel.Text = 'Light frequency: ';
            app.FreqTrainLabel_2 = uilabel(app.UIFigure);
            app.FreqTrainTextLabel_2 = uilabel(app.UIFigure);
            app.FreqTrainTextLabel_2.Text = 'Protocol Duration:';
            app.PulseDurationsEditFieldLabel = uilabel(app.UIFigure);
            app.PulseDurationsEditFieldLabel.Text = 'Pulse Duration (s)';
            app.PulseDurationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulseDurationsEditField.Value = 0.002;
            app.InterPulseIntervalsEditFieldLabel = uilabel(app.UIFigure);
            app.InterPulseIntervalsEditFieldLabel.Text = 'Inter-Pulse Interval  (s)';
            app.InterPulseIntervalsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InterPulseIntervalsEditField.Value = 0.008;
            app.PulseNbEditFieldLabel = uilabel(app.UIFigure);
            app.PulseNbEditFieldLabel.Text = 'Pulse Nb';
            app.PulseNbEditField = uieditfield(app.UIFigure, 'numeric');
            app.PulseNbEditField.Limits = [1 Inf];
            app.PulseNbEditField.RoundFractionalValues = 'on';
            app.PulseNbEditField.ValueDisplayFormat = '%.0f';
            app.PulseNbEditField.Value = 100;
            app.LightProtocolButtonGroup = uibuttongroup(app.UIFigure);
            app.LightProtocolButtonGroup.Title = 'Light Protocol';
            app.DigitalregularpulsesButton = uiradiobutton(app.LightProtocolButtonGroup);
            app.DigitalregularpulsesButton.Text = 'Digital (regular pulses)';
            app.DigitalregularpulsesButton.Value = true;
            app.AnalogsmoothedpulsesButton = uiradiobutton(app.LightProtocolButtonGroup);
            app.AnalogsmoothedpulsesButton.Text = 'Analog (smoothed pulses)';
            app.SmoothingwindowEditFieldLabel = uilabel(app.LightProtocolButtonGroup);
            app.SmoothingwindowEditFieldLabel.Text = 'Smoothing window';
            app.SmoothingwindowEditField = uieditfield(app.LightProtocolButtonGroup, 'numeric');
            app.SmoothingwindowEditField.Limits = [0 Inf];
            app.SmoothingwindowEditField.Value = 5;
            app.AnalogPlotAxe = uiaxes(app.UIFigure);
            title(app.AnalogPlotAxe, 'Custom stimulation pattern preview')
            xlabel(app.AnalogPlotAxe, 'Time (s)')
            ylabel(app.AnalogPlotAxe, 'Light Input (V)')
            zlabel(app.AnalogPlotAxe, 'Z')
            app.DigitalPlotAxe = uiaxes(app.UIFigure);
            title(app.DigitalPlotAxe, 'Custom stimulation pattern preview')
            xlabel(app.DigitalPlotAxe, 'Time (s)')
            ylabel(app.DigitalPlotAxe, 'TTL (Digital)')
            zlabel(app.DigitalPlotAxe, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CustomStimBis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end