classdef MatPulseVid < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DevicesDropDownLabel            matlab.ui.control.Label
        DevicesDropDown                 matlab.ui.control.DropDown
        SamplingrateHzEditFieldLabel    matlab.ui.control.Label
        SamplingrateHzEditField         matlab.ui.control.EditField
        TriggerCamsCheckBox             matlab.ui.control.CheckBox
        CamChannelDropDownLabel         matlab.ui.control.Label
        CamChannelDropDown              matlab.ui.control.DropDown
        LightProtocolButtonGroup        matlab.ui.container.ButtonGroup
        NoLightButton                   matlab.ui.control.RadioButton
        DigitalregularpulsesButton      matlab.ui.control.RadioButton
        AnalogsmoothedpulsesButton      matlab.ui.control.RadioButton
        DigitalOutputDropDownLabel      matlab.ui.control.Label
        DigitalOutputDropDown           matlab.ui.control.DropDown
        AnalogOutputDropDownLabel       matlab.ui.control.Label
        AnalogOutputDropDown            matlab.ui.control.DropDown
        FPSEditFieldLabel               matlab.ui.control.Label
        FPSEditField                    matlab.ui.control.EditField
        TriggerSpikeGLXCheckBox         matlab.ui.control.CheckBox
        CamChannelDropDownLabel_2       matlab.ui.control.Label
        SpikeGLXChannelDropDown         matlab.ui.control.DropDown
        CustomStimappButton             matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        PulseDurationsEditFieldLabel    matlab.ui.control.Label
        PulseDurationsEditField         matlab.ui.control.EditField
        PulseNbEditFieldLabel           matlab.ui.control.Label
        PulseNbEditField                matlab.ui.control.EditField
        InterPulseIntervalsEditFieldLabel  matlab.ui.control.Label
        InterPulseIntervalsEditField    matlab.ui.control.EditField
        RepeatntimesEditFieldLabel      matlab.ui.control.Label
        RepeatntimesEditField           matlab.ui.control.EditField
        PulsesTraindesignPanel          matlab.ui.container.Panel
        DigitalAxe                      matlab.ui.control.UIAxes
        AnalogAxe                       matlab.ui.control.UIAxes
        IntervalbetweentrainsEditFieldLabel  matlab.ui.control.Label
        IntervalbetweentrainsEditField  matlab.ui.control.EditField
        FreqTrainLabel                  matlab.ui.control.Label
        FreqTrainTextLabel              matlab.ui.control.Label
        FreqTrainLabel_2                matlab.ui.control.Label
        FreqTrainTextLabel_2            matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        LoadedLampLabel                 matlab.ui.control.Label
        LoadedLamp                      matlab.ui.control.Lamp
        RunningLampLabel                matlab.ui.control.Label
        RunningLamp                     matlab.ui.control.Lamp
        CheckButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        SmoothingwindowEditFieldLabel   matlab.ui.control.Label
        SmoothingwindowEditField        matlab.ui.control.NumericEditField
        UseCustomStimCheckBox           matlab.ui.control.CheckBox
        AnalogPowerVEditFieldLabel      matlab.ui.control.Label
        PowerVEditField                 matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        DaqList % Ni Daq List
        DaqObject % Description
        DaqObjectCounter % Description
        Channels
        DigLine
        AnaLine
        OutMat
        ChanCam
        ChanSpikeGLX
        CustomDig
        CustomAna
    end

    
    properties (Access = public)
        AnalogPowerValue % value in V to send to laser analog channel
        StimFreq % Description
    end
    
    methods (Access = public)
        
        function UpdateCustomStimOptions(app,CustomDig,CustomAna)
            
            app.CustomDig = CustomDig;
            app.CustomAna = CustomAna;
            app.UseCustomStimCheckBox.Value = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DaqList = daqlist("ni");
            app.DaqObject = daq("ni");
            
            app.DaqObject.Rate = 10000;
            
            app.SamplingrateHzEditField.Value = num2str(app.DaqObject.Rate); 
            app.DevicesDropDown.Items = app.DaqList(1,:).Model(:);
            app.SpikeGLXChannelDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(3).ChannelNames(:);
            app.SpikeGLXChannelDropDown.Value = app.DaqList(1,:).DeviceInfo.Subsystems(3).ChannelNames(1);
    
            app.CamChannelDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(4).ChannelNames(:);
            app.CamChannelDropDown.Value = app.DaqList(1,:).DeviceInfo.Subsystems(4).ChannelNames(1);
            app.FPSEditField.Value = num2str(100);
            
            app.DigitalOutputDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(3).ChannelNames(:);
            app.DigitalOutputDropDown.Value = app.DaqList(1,:).DeviceInfo.Subsystems(3).ChannelNames(2);

%             app.AnalogOutputDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(2).ChannelNames(:);
%             app.AnalogOutputDropDown.Value = app.DaqList(1,:).DeviceInfo.Subsystems(2).ChannelNames(1);
%             
            % Current Stim
            app.AnalogOutputDropDown.Items = app.DaqList(2,:).DeviceInfo.Subsystems(1).ChannelNames(:);
            app.AnalogOutputDropDown.Value = app.DaqList(2,:).DeviceInfo.Subsystems(1).ChannelNames(1);

        end

        % Drop down opening function: DevicesDropDown
        function PopulateDevices(app, event)
%             app.DevicesDropDown.Items = app.DaqList(1,:).Model(:);
        end

        % Value changed function: SamplingrateHzEditField
        function updateFs(app, event)
            app.DaqObject.Rate = str2double(app.SamplingrateHzEditField.Value);
            
        end

        % Drop down opening function: CamChannelDropDown
        function LoadCntrChans(app, event)
%             app.CamChannelDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(4).ChannelNames(:);
        end

        % Selection changed function: LightProtocolButtonGroup
        function UpdateLightChansSelection(app, event)
            selectedButton = app.LightProtocolButtonGroup.SelectedObject;
            if app.NoLightButton.Value == 1
                app.AnalogOutputDropDown.Visible = 0;
                app.DigitalOutputDropDown.Visible = 0;
                app.SmoothingwindowEditField.Visible = 0;

            elseif app.DigitalregularpulsesButton.Value == 1
                app.AnalogOutputDropDown.Visible = 1;
                app.DigitalOutputDropDown.Visible = 1;
                app.SmoothingwindowEditField.Visible = 0;
%                 app.DigitalOutputDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(4).ChannelNames(:);
%                 app.AnalogOutputDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(2).ChannelNames(:);
%                 
                
            else % Analog modulation do not allow to setup analog fixed value
                app.AnalogOutputDropDown.Visible = 1;
                app.DigitalOutputDropDown.Visible = 1;
                app.SmoothingwindowEditField.Visible = 1;
%                 app.DigitalOutputDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(4).ChannelNames(:);
%                 app.AnalogOutputDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(2).ChannelNames(:);
%                                 
            end
        end

        % Value changed function: PowerVEditField
        function UpdateAnalogPower(app, event)
            app.AnalogPowerValue = app.PowerVEditField.Value ;           
        end

        % Value changed function: InterPulseIntervalsEditField, 
        % PulseDurationsEditField
        function UpdateLightFreq(app, event)
          
            app.StimFreq = 1/ (eval(app.PulseDurationsEditField.Value) + eval(app.InterPulseIntervalsEditField.Value));
            app.FreqTrainLabel.Text = strcat(num2str(app.StimFreq),'Hz');
        end

        % Button pushed function: LoadButton
        function PreLoadStim(app, event)
%             app.DaqObject.Channels(length(app.DaqObject.Channels)+1).Meta = 'Cam'
            % RESETTING DAQ OBJECT WITH CURRENT SETTINGS
            daqreset
            app.DaqList = daqlist("ni");
            app.DaqObject = daq("ni");
            app.DaqObject.Rate = str2double(app.SamplingrateHzEditField.Value) ; 
            
            app.OutMat = [];
            tvec=linspace(1/app.DaqObject.Rate,length(app.DigLine)/app.DaqObject.Rate,length(app.DigLine));
    
            if app.TriggerSpikeGLXCheckBox.Value == 1
                app.OutMat = [app.OutMat ones(length(tvec),1)];
                app.ChanSpikeGLX =  addoutput(app.DaqObject,app.DaqList(1,:).DeviceID(1), app.SpikeGLXChannelDropDown.Value, "Digital");
            end
    
            if app.TriggerCamsCheckBox.Value == 1    
    %             y = square(2*pi*str2double(app.FPSEditField.Value)*tvec,50); % 50 is for 50% duty cycle, might be customized if shutter is TTL controlled
    %             TTLcam = (y'+1)/2;
    %             app.OutMat = [app.OutMat TTLcam];      
                app.DaqObjectCounter = daq("ni");
                app.DaqObjectCounter.Rate = str2double(app.SamplingrateHzEditField.Value);
                app.ChanCam = addoutput(app.DaqObjectCounter,app.DaqList(1,:).DeviceID(1), app.CamChannelDropDown.Value, "PulseGeneration");
                app.ChanCam.Frequency = str2double(app.FPSEditField.Value);
                app.ChanCam.DutyCycle = 0.5;
                
            end
            
            % Preparing Analog and Digital Channel of the board
            if app.NoLightButton.Value == 0
                ChanLaserDig = addoutput(app.DaqObject, ...
                app.DaqList(1,:).DeviceID(1), app.DigitalOutputDropDown.Value, "Digital");
                app.Channels.Names{length(app.DaqObject.Channels)}= 'ChanLaserDig';
                app.Channels.Ports{length(app.DaqObject.Channels)}= app.DigitalOutputDropDown.Value;                
                                
%                 ChanLaserAna = addoutput(app.DaqObject,app.DaqList(1,:).DeviceID(1),app.AnalogOutputDropDown.Value,"Voltage");
                ChanLaserAna = addoutput(app.DaqObject,app.DaqList(2,:).DeviceID(1),app.AnalogOutputDropDown.Value,"Current");
                ChanLaserFake = addoutput(app.DaqObject,app.DaqList(1,:).DeviceID(1),"ao1","Voltage");
                
                
                app.Channels.Names{length(app.DaqObject.Channels)}= 'ChanLaserAna';
                app.Channels.Ports{length(app.DaqObject.Channels)}= app.AnalogOutputDropDown.Value;
            end   
                
            
            if app.NoLightButton.Value == 0
                app.OutMat = [app.OutMat app.DigLine app.AnaLine app.AnaLine]; % Second analine to delete. hack for current stim 
            end
    
            app.StartButton.Enable = 1;
    
            app.LoadedLamp.Color = [0 1 0];
            app.RunningLamp.Enable = 1;
        end

        % Button pushed function: StartButton
        function StartDaq(app, event)
            
            app.RunningLamp.Color = [0 1 0];
            app.StopButton.Enable = 1;
            
            % Case with Laser, SpikeGLX and Cam triggering
            if app.NoLightButton.Value == 1 && app.TriggerSpikeGLXCheckBox.Value == 1 && app.TriggerCamsCheckBox.Value == 1
                write(app.DaqObject,1)
                start(app.DaqObjectCounter,'Continuous')
            % Case with NO Laser, but SpikeGLX and Cam triggering
            elseif app.NoLightButton.Value == 0 && app.TriggerSpikeGLXCheckBox.Value == 1 && app.TriggerCamsCheckBox.Value == 1            
%             if app.NoLightButton.Value == 0
                preload(app.DaqObject,app.OutMat)
                start(app.DaqObject)
                start(app.DaqObjectCounter,'Continuous')
                while app.DaqObject.Running == 1
                    pause(0.1)
                end
                write(app.DaqObject,[0 0 0 0]) % Hacked delete one zero
                stop(app.DaqObject)
                stop(app.DaqObjectCounter)
                
                app.RunningLamp.Color = [1 0 0];
                app.LoadedLamp.Color = [1 0 0];
                app.LoadButton.Enable = 0;
                app.CheckButton.Enable = 1;
                app.StartButton.Enable = 0;
            % Case with Laser triggering, SpikeGLX, but no cam triggering    
            elseif app.NoLightButton.Value == 0 && app.TriggerSpikeGLXCheckBox.Value == 1 && app.TriggerCamsCheckBox.Value == 0            
                preload(app.DaqObject,app.OutMat)
                start(app.DaqObject)
                while app.DaqObject.Running == 1
                    pause(0.1)
                end
                write(app.DaqObject,[0 0 0 0]) % Hack: Delete a zero for voltage mode
                stop(app.DaqObject)
                app.RunningLamp.Color = [1 0 0];
                app.LoadedLamp.Color = [1 0 0];
                app.LoadButton.Enable = 0;
                app.CheckButton.Enable = 1;
                app.StartButton.Enable = 0;
                app.StopButton.Enable = 0;

            % Case with no light, no cam, just SpikeGLX
            elseif app.NoLightButton.Value == 1 && app.TriggerSpikeGLXCheckBox.Value == 1 && app.TriggerCamsCheckBox.Value == 0
                
                write(app.DaqObject,[1])                
            end
%             else
%                 start(app.DaqObject,'Continuous')
%             end
%             
%             write(app.DaqObject,[app.AnaLine app.DigLine])

%             write(app.DaqObject,zeros(1,size(app.OutMat,2)))

        end

        % Button pushed function: CheckButton
        function CheckStim(app, event)
            app.DaqObject.Rate = str2double(app.SamplingrateHzEditField.Value) ; 

            if app.UseCustomStimCheckBox.Value == 0
%                 app.DaqObject.Rate = str2double(app.SamplingrateHzEditField.Value) ; 
%     %             app.DevicesDropDown.Items = app.DaqList(1,:).Model(:);
%     %             app.CamChannelDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(4).ChannelNames(:);
%     %             app.DigitalOutputDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(4).ChannelNames(:);
%     %             app.AnalogOutputDropDown.Items = app.DaqList(1,:).DeviceInfo.Subsystems(2).ChannelNames(:);
%     
%                 
%                 if app.TriggerCamsCheckBox.Value == 1
%                     app.DaqObjectCounter = daq("ni");
%                     app.DaqObjectCounter.Rate = str2double(app.SamplingrateHzEditField.Value);
%     %                 app.Channels.Names{length(app.DaqObject.Channels)}= 'TriggerCams';
%     %                 app.Channels.Ports{length(app.DaqObject.Channels)}= app.CamChannelDropDown.Value;
%     
%                 end
%                 if app.TriggerSpikeGLXCheckBox.Value == 1
%                     app.ChanSpikeGLX = addoutput(app.DaqObject,app.DaqList(1,:).DeviceID(1), app.SpikeGLXChannelDropDown.Value, "Digital");
%                     app.Channels.Names{length(app.DaqObject.Channels)}= 'TriggerSpikeGLX';
%                     app.Channels.Ports{length(app.DaqObject.Channels)}= app.SpikeGLXChannelDropDown.Value;
%     
%                 end
                
                
                if app.NoLightButton.Value == 0
    
               
                    %% Monotonous Single pulses in trains, random or fixed Inter train interval
                    % Signal Preparation for full stim protocol
                    AnaValue = app.PowerVEditField.Value;
                    PulseDur = eval(app.PulseDurationsEditField.Value);
                    PulseNb = eval(app.PulseNbEditField.Value);            
                    PulseInterv = eval(app.InterPulseIntervalsEditField.Value);
                    
                    TrainNb = eval(app.RepeatntimesEditField.Value);
                    TrainInterv = eval(app.IntervalbetweentrainsEditField.Value);
                    
        
                    % Train and pulses generation  
                    app.AnaLine = [];
                    app.DigLine = [];
              
        
                    if numel(TrainInterv) == 1
        
                        % First add zeros before pulses according to TrainInterv
                        app.DigLine=[app.DigLine; zeros(round(TrainInterv*app.DaqObject.Rate),1)];
                        
                        for t=1:TrainNb
                            if numel(PulseInterv) == 1
                                for p=1:PulseNb
                                    app.DigLine=[app.DigLine; ones(round(PulseDur*app.DaqObject.Rate),1); zeros(round(PulseInterv(1)*app.DaqObject.Rate),1)];
                                end
                                app.DigLine=[app.DigLine; zeros(round(TrainInterv*app.DaqObject.Rate),1)];
                            
                            elseif numel(PulseInterv) == 2
                                for p=1:PulseNb
                                    app.DigLine=[app.DigLine; ones(round(PulseDur*app.DaqObject.Rate),1); zeros(round(rdelays(p+1)*app.DaqObject.Rate),1)];
                                end
                            end
                                
                        end
                        app.DigLine=[app.DigLine; zeros(round(TrainInterv*app.DaqObject.Rate),1)];
                    
                    elseif numel(TrainInterv) == 2
                        
                        rdelaysTrain = TrainInterv(1) + (TrainInterv(2)-TrainInterv(1)).*rand(TrainNb+1,1); 
                        app.DigLine=[app.DigLine; zeros(round(rdelaysTrain(1)*app.DaqObject.Rate),1)];
        
                        for t=1:TrainNb
                            if numel(PulseInterv) == 1
                                for p=1:PulseNb
                                    app.DigLine=[app.DigLine; ones(round(PulseDur*app.DaqObject.Rate),1); zeros(round(PulseInterv(1)*app.DaqObject.Rate),1)];
                                end
                                app.DigLine=[app.DigLine; zeros(round(rdelaysTrain(t+1)*app.DaqObject.Rate),1)]; % Fix interval after each train
                            
                            elseif numel(PulseInterv) == 2
                                
                                rdelaysPulse = PulseInterv(1) + (PulseInterv(2)-PulseInterv(1)).*rand(PulseNb,1); % Random interval after each pulse
                                for p=1:PulseNb
                                    app.DigLine=[app.DigLine; ones(round(PulseDur*app.DaqObject.Rate),1); zeros(round(rdelaysPulse(p)*app.DaqObject.Rate),1)];
                                end
                                app.DigLine=[app.DigLine; zeros(round(rdelaysTrain(t+1)*app.DaqObject.Rate),1)]; % Fix interval after each train
        
                            end                    
                        end          
                    end
                    % End of train and pulses generation
                    app.AnaLine =  ones(length(app.DigLine),1);
                    app.AnaLine(app.DigLine > 0) = app.PowerVEditField.Value;
    
                    % Converting in analog trace to smooth pulse edges if
                    % requested
                    if app.AnalogsmoothedpulsesButton.Value == 1
                       app.AnaLine = app.DigLine .* app.PowerVEditField.Value;
                        if app.SmoothingwindowEditField.Value > 0
                            app.AnaLine = smooth(app.AnaLine,app.SmoothingwindowEditField.Value);
                        end
                       app.DigLine = app.AnaLine > 0;
                    end
    
               end
            elseif app.UseCustomStimCheckBox.Value == 1
                app.AnaLine = app.CustomAna;
                app.DigLine = app.CustomDig;
            end
            
        if app.NoLightButton.Value == 0
            tvec=linspace(1/app.DaqObject.Rate,length(app.DigLine)/app.DaqObject.Rate,length(app.DigLine));
            plot(app.DigitalAxe, tvec,app.DigLine);
            plot(app.AnalogAxe, tvec,app.AnaLine);
            linkaxes([app.DigitalAxe app.AnalogAxe], 'x')
            xlim(app.DigitalAxe,[tvec(1) tvec(end)])
            ylim(app.DigitalAxe,[-0.1 1.1])
        else
            warndlg('Note that stimulation is not activated.')
        end
            app.LoadButton.Enable = 1;        
        end

        % Button pushed function: StopButton
        function StopDaq(app, event)
            if app.TriggerCamsCheckBox.Value == 1 && app.NoLightButton.Value == 1
                stop(app.DaqObjectCounter)
                write(app.DaqObject,0)
            elseif app.TriggerCamsCheckBox.Value == 1 && app.NoLightButton.Value == 0 
                stop(app.DaqObject)
                stop(app.DaqObjectCounter)
                write(app.DaqObject,[0 0 0 0]) % Hacked, delete one zero for voltage mode
            elseif app.TriggerCamsCheckBox.Value == 0 && app.NoLightButton.Value == 0 
                stop(app.DaqObject)
                write(app.DaqObject,[0 0 0 0]) % Hacked, delete one zero for voltage mode
            elseif app.TriggerCamsCheckBox.Value == 0 && app.NoLightButton.Value == 1
%                 stop(app.DaqObject)
                write(app.DaqObject,0)

            end

            app.LoadButton.Enable = 0;
            app.CheckButton.Enable = 1;
            app.StartButton.Enable = 0;
                
            app.LoadedLamp.Color = [1 0 0];
            app.RunningLamp.Color = [1 0 0];
            app.CheckButton.Enable = 1;        
            app.StopButton.Enable = 0;
        end

        % Button pushed function: CustomStimappButton
        function OpenCustomStimApp(app, event)
            CustomStim(app);
        end

        % Value changed function: UseCustomStimCheckBox
        function CheckForCustomStim(app, event)
            value = app.UseCustomStimCheckBox.Value;
            if value == 1 
                if isempty(app.CustomAna)
                    warndlg('No Custom stimulation specified, click Custom Stim app button')
                    app.UseCustomStimCheckBox.Value = 0;
                end
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {524, 524};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DevicesDropDownLabel = uilabel(app.LeftPanel);
            app.DevicesDropDownLabel.Text = 'Devices';
            app.DevicesDropDown = uidropdown(app.LeftPanel);
            app.DevicesDropDown.DropDownOpeningFcn = createCallbackFcn(app, @PopulateDevices, true);
            app.SamplingrateHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingrateHzEditFieldLabel.Text = 'Sampling rate (Hz)';
            app.SamplingrateHzEditField = uieditfield(app.LeftPanel, 'text');
            app.SamplingrateHzEditField.ValueChangedFcn = createCallbackFcn(app, @updateFs, true);
            app.TriggerCamsCheckBox = uicheckbox(app.LeftPanel);
            app.TriggerCamsCheckBox.Text = 'Trigger Cam(s)';
            app.CamChannelDropDownLabel = uilabel(app.LeftPanel);
            app.CamChannelDropDownLabel.Text = 'Cam Channel';
            app.CamChannelDropDown = uidropdown(app.LeftPanel);
            app.CamChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @LoadCntrChans, true);
            app.LightProtocolButtonGroup = uibuttongroup(app.LeftPanel);
            app.LightProtocolButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @UpdateLightChansSelection, true);
            app.LightProtocolButtonGroup.Title = 'Light Protocol';
            app.NoLightButton = uiradiobutton(app.LightProtocolButtonGroup);
            app.NoLightButton.Text = 'No Light';
            app.NoLightButton.Value = true;
            app.DigitalregularpulsesButton = uiradiobutton(app.LightProtocolButtonGroup);
            app.DigitalregularpulsesButton.Text = 'Digital (regular pulses)';
            app.AnalogsmoothedpulsesButton = uiradiobutton(app.LightProtocolButtonGroup);
            app.AnalogsmoothedpulsesButton.Text = 'Analog (smoothed pulses)';
            app.DigitalOutputDropDownLabel = uilabel(app.LeftPanel);
            app.DigitalOutputDropDownLabel.Text = 'Digital Output';
            app.DigitalOutputDropDown = uidropdown(app.LeftPanel);
            app.AnalogOutputDropDownLabel = uilabel(app.LeftPanel);
            app.AnalogOutputDropDownLabel.Text = 'Analog Output';
            app.AnalogOutputDropDown = uidropdown(app.LeftPanel);
            app.FPSEditFieldLabel = uilabel(app.LeftPanel);
            app.FPSEditFieldLabel.Text = 'FPS';
            app.FPSEditField = uieditfield(app.LeftPanel, 'text');
            app.TriggerSpikeGLXCheckBox = uicheckbox(app.LeftPanel);
            app.TriggerSpikeGLXCheckBox.Enable = 'off';
            app.TriggerSpikeGLXCheckBox.Text = 'Trigger SpikeGLX';
            app.TriggerSpikeGLXCheckBox.Value = true;
            app.CamChannelDropDownLabel_2 = uilabel(app.LeftPanel);
            app.CamChannelDropDownLabel_2.Text = 'SpikeGLX Chan.';
            app.SpikeGLXChannelDropDown = uidropdown(app.LeftPanel);
            app.CustomStimappButton = uibutton(app.LeftPanel, 'push');
            app.CustomStimappButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCustomStimApp, true);
            app.CustomStimappButton.Text = 'Custom Stim app ';
            app.RightPanel = uipanel(app.GridLayout);
            app.PulseDurationsEditFieldLabel = uilabel(app.RightPanel);
            app.PulseDurationsEditFieldLabel.Text = 'Pulse Duration (s)';
            app.PulseDurationsEditField = uieditfield(app.RightPanel, 'text');
            app.PulseDurationsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateLightFreq, true);
            app.PulseDurationsEditField.Value = '0.002';
            app.PulseNbEditFieldLabel = uilabel(app.RightPanel);
            app.PulseNbEditFieldLabel.Text = 'Pulse Nb';
            app.PulseNbEditField = uieditfield(app.RightPanel, 'text');
            app.PulseNbEditField.Value = '100';
            app.InterPulseIntervalsEditFieldLabel = uilabel(app.RightPanel);
            app.InterPulseIntervalsEditFieldLabel.Text = 'Inter-Pulse Interval  (s)';
            app.InterPulseIntervalsEditField = uieditfield(app.RightPanel, 'text');
            app.InterPulseIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateLightFreq, true);
            app.InterPulseIntervalsEditField.Value = '0.008';
            app.RepeatntimesEditFieldLabel = uilabel(app.RightPanel);
            app.RepeatntimesEditFieldLabel.Text = 'Repeat n times';
            app.RepeatntimesEditField = uieditfield(app.RightPanel, 'text');
            app.RepeatntimesEditField.Value = '20';
            app.PulsesTraindesignPanel = uipanel(app.RightPanel);
            app.PulsesTraindesignPanel.Title = 'Pulses Train design';
            app.DigitalAxe = uiaxes(app.PulsesTraindesignPanel);
            title(app.DigitalAxe, 'Digital Channel')
            xlabel(app.DigitalAxe, 'Time (s)')
            ylabel(app.DigitalAxe, 'TTL')
            zlabel(app.DigitalAxe, 'Z')
            app.AnalogAxe = uiaxes(app.PulsesTraindesignPanel);
            title(app.AnalogAxe, 'Analog Channel')
            xlabel(app.AnalogAxe, 'Time (s)')
            ylabel(app.AnalogAxe, 'Tension (v)')
            zlabel(app.AnalogAxe, 'Z')
            app.IntervalbetweentrainsEditFieldLabel = uilabel(app.RightPanel);
            app.IntervalbetweentrainsEditFieldLabel.Text = 'Interval between trains';
            app.IntervalbetweentrainsEditField = uieditfield(app.RightPanel, 'text');
            app.IntervalbetweentrainsEditField.Value = '[3 5]';
            app.FreqTrainLabel = uilabel(app.RightPanel);
            app.FreqTrainTextLabel = uilabel(app.RightPanel);
            app.FreqTrainTextLabel.Text = 'Light frequency: ';
            app.FreqTrainLabel_2 = uilabel(app.RightPanel);
            app.FreqTrainTextLabel_2 = uilabel(app.RightPanel);
            app.FreqTrainTextLabel_2.Text = 'Protocol Duration:';
            app.LoadButton = uibutton(app.RightPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @PreLoadStim, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.StartButton = uibutton(app.RightPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartDaq, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.LoadedLampLabel = uilabel(app.RightPanel);
            app.LoadedLampLabel.Text = 'Loaded';
            app.LoadedLamp = uilamp(app.RightPanel);
            app.RunningLampLabel = uilabel(app.RightPanel);
            app.RunningLampLabel.Enable = 'off';
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.RightPanel);
            app.CheckButton = uibutton(app.RightPanel, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckStim, true);
            app.CheckButton.Text = 'Check';
            app.StopButton = uibutton(app.RightPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopDaq, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'Stop';
            app.SmoothingwindowEditFieldLabel = uilabel(app.RightPanel);
            app.SmoothingwindowEditFieldLabel.Text = 'Smoothing window';
            app.SmoothingwindowEditField = uieditfield(app.RightPanel, 'numeric');
            app.SmoothingwindowEditField.Limits = [0 Inf];
            app.SmoothingwindowEditField.Value = 5;
            app.UseCustomStimCheckBox = uicheckbox(app.RightPanel);
            app.UseCustomStimCheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckForCustomStim, true);
            app.UseCustomStimCheckBox.Text = 'Use Custom Stim';
            app.AnalogPowerVEditFieldLabel = uilabel(app.RightPanel);
            app.AnalogPowerVEditFieldLabel.Text = 'Analog Power (V)';
            app.PowerVEditField = uieditfield(app.RightPanel, 'numeric');
            app.PowerVEditField.ValueChangedFcn = createCallbackFcn(app, @UpdateAnalogPower, true);
            app.PowerVEditField.Value = 0.3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MatPulseVid

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end